classdef sharegen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FILTERUIFigure                matlab.ui.Figure
        ResetButton                   matlab.ui.control.Button
        ShareImageLabel               matlab.ui.control.Label
        SecretImageLabel              matlab.ui.control.Label
        shareimage                    matlab.ui.control.Image
        GenerateButton                matlab.ui.control.Button
        StatusLabel                   matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        NumberofSharesEditFieldLabel  matlab.ui.control.Label
        ENHANCEDVISUALCRYPTOGRAPHYSHAREGENERATORLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        BrowseImageButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        SelectedImageFile
    end
    
    methods (Access = private)


function [redComponent, greenComponent, blueComponent] = splitRGB(~,inputImage)
        redComponent = inputImage(:,:,1);
        greenComponent = inputImage(:,:,2);
        blueComponent = inputImage(:,:,3);
end

function ditheredImage = applyFloydSteinbergDithering(~,inputChannel)
    inputImage = double(inputChannel);
    [height, width] = size(inputImage);
    outputImage = zeros(height, width);

    for y = 1:height
        for x = 1:width
            oldPixel = inputImage(y, x);
            newPixel = 255 * (oldPixel > 127);
            error = oldPixel - newPixel;

            outputImage(y, x) = newPixel;

            if x < width
                inputImage(y, x + 1) = inputImage(y, x + 1) + error * 7 / 16;
            end
            if x > 1 && y < height
                inputImage(y + 1, x - 1) = inputImage(y + 1, x - 1) + error * 3 / 16;
            end
            if y < height
                inputImage(y + 1, x) = inputImage(y + 1, x) + error * 5 / 16;
            end
            if x < width && y < height
                inputImage(y + 1, x + 1) = inputImage(y + 1, x + 1) + error * 1 / 16;
            end
        end
    end

    ditheredImage = uint8(outputImage);
end

function share_images = generate_shares(~, input_image, n)
    input_image = imbinarize(input_image);
    [height, width] = size(input_image);

    share_arrays = cell(1, n);

    for i = 1:n
        share_arrays{i} = zeros(height, width);  % Use curly braces for cell array indexing

        for x = 1:width
            for y = 1:height
                original_pixel = input_image(y, x);
                share_pixels = randi([0, 1], 1, n - 1);
                share_pixels = [share_pixels, bitxor(original_pixel, mod(sum(share_pixels), 2))];

                for k = 1:n
                    share_arrays{k}(y, x) = share_pixels(k);  % Corrected indexing order (y, x)
                end
            end
        end

        for k = 1:n
            share_images = uint8(share_arrays{k} * 255);
        end
    end
end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
    [filename, filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'; '*.*', 'All Files (*.*)'}, 'Select an image', 'MultiSelect', 'off');
    if isequal(filename, 0)
        app.Label.Text = '';
        return;
    end

    app.Label.Text = filename;

    % Load your image
    imageData = imread(fullfile(filepath, filename));
    app.Image.ImageSource = imageData;
    app.SelectedImageFile = imageData;

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
% Check if an image is selected
if isempty(app.SelectedImageFile)
    app.StatusLabel.FontColor = [1, 0, 0];
    app.StatusLabel.Text = 'Please select an image first!';
    return;
end

% Get the number of shares
numShares = app.EditField.Value;
numShares = str2double(numShares);

% Check if numShares is a positive integer
if ~isnan(numShares) && numShares > 1 && round(numShares) == numShares
    app.StatusLabel.Text = '';
    app.StatusLabel.FontColor = [0.39, 0.83, 0.07];
    app.StatusLabel.Text = 'Generating...';

    % Generate shares for the red, green, and blue components
    input_image = app.SelectedImageFile;
    [redComponent, greenComponent, blueComponent] = splitRGB(app, input_image);
    dred = applyFloydSteinbergDithering(app, redComponent);
    dgreen = applyFloydSteinbergDithering(app, greenComponent);
    dblue = applyFloydSteinbergDithering(app, blueComponent);
    red_share = generate_shares(app, dred, numShares);
    green_share = generate_shares(app, dgreen, numShares);
    blue_share = generate_shares(app, dblue, numShares);
    rgbShares = cell(1, numShares);

    % Iterate through shares and generate images
    for i = 1:numShares
        redShare = red_share(i);
        greenShare = green_share(i);
        blueShare = blue_share(i);
        rgbShare = cat(3, redShare, greenShare, blueShare);
        rgbShares{i} = rgbShare;
        imwrite(rgbShares{i}, ['Share' num2str(i) '.png']);
        app.StatusLabel.FontColor = [0.39, 0.83, 0.07];
        app.StatusLabel.Text = 'Generated Shares';
        app.shareimage.ImageSource = rgbShares{i};
    end
else
    app.StatusLabel.Text = 'Please enter a valid positive integer greater than 1.';
end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
    pathToMLAPP = fileparts(mfilename('fullpath'));
    app.Image.ImageSource = fullfile(pathToMLAPP, 'InsertPlaceholder.png');
    app.shareimage.ImageSource = fullfile(pathToMLAPP, 'SharePlaceholder.png');
    app.SelectedImageFile = [];
    app.Label.Text = '';
    app.StatusLabel.Text = '';
    app.EditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FILTERUIFigure = uifigure('Visible', 'off');
            app.FILTERUIFigure.Name = 'FILTER';
            app.BrowseImageButton = uibutton(app.FILTERUIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.Label = uilabel(app.FILTERUIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.FILTERUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'InsertPlaceholder.png');
            app.ENHANCEDVISUALCRYPTOGRAPHYSHAREGENERATORLabel = uilabel(app.FILTERUIFigure);
            app.ENHANCEDVISUALCRYPTOGRAPHYSHAREGENERATORLabel.Text = 'ENHANCED VISUAL CRYPTOGRAPHY - SHARE GENERATOR';
            app.NumberofSharesEditFieldLabel = uilabel(app.FILTERUIFigure);
            app.NumberofSharesEditFieldLabel.Text = 'Number of Shares';
            app.EditField = uieditfield(app.FILTERUIFigure, 'text');
            app.StatusLabel = uilabel(app.FILTERUIFigure);
            app.StatusLabel.Text = '';
            app.GenerateButton = uibutton(app.FILTERUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.shareimage = uiimage(app.FILTERUIFigure);
            app.shareimage.ImageSource = fullfile(pathToMLAPP, 'SharePlaceholder.png');
            app.SecretImageLabel = uilabel(app.FILTERUIFigure);
            app.SecretImageLabel.Text = 'Secret Image';
            app.ShareImageLabel = uilabel(app.FILTERUIFigure);
            app.ShareImageLabel.Text = 'Share Image';
            app.ResetButton = uibutton(app.FILTERUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.FILTERUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sharegen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FILTERUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FILTERUIFigure)
        end
    end
end